classdef visualization_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        UIAxes                   matlab.ui.control.UIAxes
        UIAxes2                  matlab.ui.control.UIAxes
        Button                   matlab.ui.control.Button
        VentanaDropDown          matlab.ui.control.DropDown
        nfftDropDown             matlab.ui.control.DropDown
        TamaodeventanaEditField  matlab.ui.control.NumericEditField
        VentanaLabel             matlab.ui.control.Label
        TamaodeventanaLabel      matlab.ui.control.Label
        nfftLabel                matlab.ui.control.Label
        DropDown                 matlab.ui.control.DropDown
        TamaodeventanaEditFieldSpectrogram  matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        time % Description
        fs = 48000; % Description
        CallingApp % Description
        mode % 0: ft 1: fft
        wSize % Tamaño de ventana del SFTF
        wSizeSpectrogram % Tamaño de ventana del espectrograma
        nfft % Description
        wType % Tipo de ventada
        center % Situación del puntero sobre la señal
        limX % límites del eje X en el plot inferior
        numerical_warning = 'El valor introducido debe ser un número'; % Description
        even_warning = 'El valor introducido debe ser par'; % Description
        wsize_warning = 'El valor introducido debe estar entre 0 y 47999'; % Description
        window_warning = 'La ventana no puede situarse fuera de la señal'; % Description
        ft_title = 'Transformada de Fourier';
        stft_title = 'Transformada de Fourier de tiempo reducido (STFT)';
    end
    
    properties (Access = public)
        signal = []; % Description
        spectrum = []; % Description
        freq = []; % Description
    end
    
    methods (Access = public)
        
        function updatePlot(app,signal,t,varargin)
            app.signal = signal;
            app.time = t;
            
            %Realizar fft de la señal
            Y = fft(app.signal);
            P2 = abs(Y/length(app.signal));
            app.spectrum = P2(1:length(app.signal)/2+1);
            app.spectrum(2:end-1) = 2*app.spectrum(2:end-1);
            
            app.freq = app.fs*(0:(length(app.signal)/2))/length(app.signal);
            
            plot(app.UIAxes,app.time,app.signal,'b');
            
            % Ajusta el eje Y a la señal
            if ~isempty(varargin)
                app.UIAxes.YLim = [0,1];
            else
                if(max(app.signal)>1)
                    app.UIAxes.YLim = [-ceil(max(app.signal)),ceil(max(app.signal))];
                else
                    app.UIAxes.YLim = [-1,1];
                end
            end
            
            % Ajusta el eje X a las frecuencias relevantes del fft
            plot(app.UIAxes2,app.freq,app.spectrum,'b','Marker','.');
            xa = find(app.spectrum > 10^-2,1);
            xb = find(app.spectrum > 10^-2,1,'last');
            
            if isempty(xa)
                xa = 0;
            else
                xa = app.freq(xa);
            end
            
            if isempty(xb)
                xb = 24000;
            else
                xb = app.freq(xb);
            end
            
            if xa > 10
                xa = round(xa*0.8);
            else
                xa = 0;
            end
            
            if xb < 10
                xb = 10;
            elseif xb*1.2 < 24000
                xb = xb*1.2;
            end
            
            app.limX = [xa,xb];
            app.UIAxes2.XLim = app.limX;
            
            app.Button.Enable = 'on';
        end
    end
    
    methods (Access = private)
        
        
        function resizeWindow(app)
            % Dimensiones de las ventanas en una pantalla de 1280x1024
            originalw = 634;
            originalh = 554;
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            % Centrar la ventana en la pantalla
            hpos = screenw/2-neww/2;
            vpos = screenh/2-newh/2;
            
            app.UIFigure.Position = [hpos vpos neww newh];
            
        end
        
        function resizeelement(~,element)
            
            originalhpos = element.Position(1);
            originalvpos = element.Position(2);
            originalw = element.Position(3);
            originalh = element.Position(4);
            
            % Dimensiones de la nueva pantalla
            ssize = get(0,'ScreenSize');
            screenw = ssize(3);
            screenh = ssize(4);
            
            % Ajustar dimensión de la ventana a la nueva pantalla
            neww = round(originalw*screenw/1280);
            newh = round(originalh*screenh/1024);
            
            newhpos = round(originalhpos*screenw/1280);
            newvpos = round(originalvpos*screenh/1024);
            
            element.Position = [newhpos newvpos neww newh];
            
            element.FontSize = round(element.FontSize*(screenw*screenh)/(1280*1024));
            
        end
        
        %         function translate(app,language)
        
        %         end
        
        function translate(app,language)
            load('language.mat','visualization')
            
            app.UIFigure.Name = visualization.name{language};
            
            app.UIAxes.YLabel.String = visualization.amplitude{language};
            app.UIAxes.XLabel.String = visualization.time{language};
            app.UIAxes.Title.String = visualization.signal{language};
            
            app.UIAxes2.YLabel.String = visualization.amplitude{language};
            app.UIAxes2.XLabel.String = visualization.frequency{language};
            app.ft_title = visualization.ft_title{language};
            app.stft_title = visualization.stft_title{language};
            title(app.UIAxes2,app.ft_title);
            
            app.VentanaLabel.Text = visualization.window{language};
            app.VentanaDropDown.Items = visualization.window_type{language};
            app.TamaodeventanaLabel.Text = visualization.window_size{language};
            
            
            app.even_warning = visualization.pair_warning{language};
            app.numerical_warning = visualization.numerical_warning{language};
            app.wsize_warning = visualization.wsize_warning{language};
            app.window_warning = visualization.window_warning{language};
            
            clear('visualization')
        end
        
        
        function plotCenterLine(app,pt,newClick)
            if pt(1) < 0
                pt(1) = mean(app.UIAxes.XLim);
            end
            
            cla(app.UIAxes);
            hold(app.UIAxes,'off')
            
            xa = pt(1) - (app.wSize-1)/(2*app.fs);
            xwidth = app.wSize/app.fs;
            if(xa < app.UIAxes.XLim(1) || xa + xwidth > app.UIAxes.XLim(2))
                pt(1) = mean(app.UIAxes.XLim);
                xa = pt(1) - (app.wSize-1)/(2*app.fs);
                xwidth = app.wSize/app.fs;
                if newClick == 1
                    warn = warndlg(app.window_warning);
                    uiwait(warn);
                end
            end
            app.center = round(pt(1)*app.fs);
            ya = app.UIAxes.YLim(1);
            yheight = app.UIAxes.YLim(2) - app.UIAxes.YLim(1);
            
            rectangle(app.UIAxes,'Position',[xa,ya,xwidth,yheight],"FaceColor",[.9 .9 .9],'LineStyle',"none");
            
            hold(app.UIAxes,'on')
            plot(app.UIAxes,app.time,app.signal)
            plot(app.UIAxes,[pt(1),pt(1)],app.UIAxes.YLim,'k--')
            
            app.calculateSTFT();
        end
        
        function addDataTip(app,pt)
            ptx = round(pt(1)*app.fs);
            if ptx < 1
                hold(app.UIAxes,'off')
                plot(app.UIAxes,app.time,app.signal)
                return
            end
            
            hold(app.UIAxes,'off')
            plot(app.UIAxes,app.time,app.signal)
            hold(app.UIAxes,'on')
            plot(app.UIAxes,[pt(1),pt(1)],app.UIAxes.YLim,'k:')
            plot(app.UIAxes,app.UIAxes.XLim,[1 1]*app.signal(ptx),'k:')
            %             plot(app.UIAxes,pt(1),app.signal(ptx),'rx','MarkerSize',8)
            text(app.UIAxes,app.UIAxes.XLim(1),app.UIAxes.YLim(1),sprintf(' x: %.2f\n y: %.2f',pt(1),app.signal(ptx)),'FontSize',14,"FontWeight","bold",'VerticalAlignment',"bottom")
            %             text(app.UIAxes,app.UIAxes.XLim(2)*0.05,app.UIAxes.YLim(2)*0.95,sprintf('x: %.2f',pt(1)),'FontSize',14,"FontWeight","bold")
            %             text(app.UIAxes,app.UIAxes.XLim(2)*0.05,app.UIAxes.YLim(2)*0.75,sprintf('y: %.2f',app.signal(ptx)),'FontSize',14,"FontWeight","bold")
        end
        
        function addDataTipSpectrum(app,pt)
            if pt(1) < 0
                return
            end
            
            [~,ptx] = min(abs(app.freq-pt(1)));
            
            hold(app.UIAxes2,'off')
            plot(app.UIAxes2,app.freq,app.spectrum,'b','Marker','.')
            hold(app.UIAxes2,'on')
            plot(app.UIAxes2,[1,1]*app.freq(ptx),app.UIAxes2.YLim,'k:')
            plot(app.UIAxes2,app.UIAxes2.XLim,[1 1]*app.spectrum(ptx),'k:')
            %             plot(app.UIAxes,pt(1),app.signal(ptx),'rx','MarkerSize',8)
            text(app.UIAxes2,app.UIAxes2.XLim(1),app.UIAxes2.YLim(1),sprintf(' x: %.2f\n y: %.2f',app.freq(ptx),app.spectrum(ptx)),'FontSize',14,"FontWeight","bold",'VerticalAlignment',"bottom")
            %             text(app.UIAxes,app.UIAxes.XLim(2)*0.05,app.UIAxes.YLim(2)*0.95,sprintf('x: %.2f',pt(1)),'FontSize',14,"FontWeight","bold")
            %             text(app.UIAxes,app.UIAxes.XLim(2)*0.05,app.UIAxes.YLim(2)*0.75,sprintf('y: %.2f',app.signal(ptx)),'FontSize',14,"FontWeight","bold")
        end
        
        function calculateSTFT(app)
            
            switch app.wType
                case 1
                    x = app.signal(app.center - (app.wSize-1)/2:app.center + (app.wSize-1)/2);
                case 2
                    x = hann(app.wSize)'.*app.signal(app.center - (app.wSize-1)/2:app.center + (app.wSize-1)/2);
            end
            
            app.spectrum = pwelch(x,app.wSize,0,app.nfft);
            app.freq = linspace(0,24000,length(app.spectrum));
            
            cla(app.UIAxes2);
            colorbar(app.UIAxes2,"off");
            
            hold(app.UIAxes2,'off')
            plot(app.UIAxes2,app.freq,app.spectrum,'b','Marker','.');
            
            app.UIAxes2.XLim = app.limX;
            app.UIAxes2.YLim(2) = max(app.spectrum);
            
            title(app.UIAxes2,app.stft_title)
        end
        
        function calculateFT(app)
            %Realizar fft de la señal
            Y = fft(app.signal);
            P2 = abs(Y/length(app.signal));
            app.spectrum = P2(1:length(app.signal)/2+1);
            app.spectrum(2:end-1) = 2*app.spectrum(2:end-1);
            
            app.freq = app.fs*(0:(length(app.signal)/2))/length(app.signal);
            
            cla(app.UIAxes2);
            colorbar(app.UIAxes2,"off");
            
            % Ajusta el eje X a las frecuencias relevantes del fft
            plot(app.UIAxes2,app.freq,app.spectrum,'b','Marker','.');
            
            app.UIAxes2.XLim = app.limX;
            app.UIAxes2.YLim(2) = max(app.spectrum);
            
            title(app.UIAxes2,app.ft_title)
        end
        
        function calculateSpectrogram(app)
            [s,f,t] = spectrogram(app.signal,app.wSizeSpectrogram,0,app.nfft,app.fs);
            
            cla(app.UIAxes2);
            imagesc(app.UIAxes2,t,f,abs(s));
            set(app.UIAxes2,'YDir', 'normal'); % flip the Y Axis so lower frequencies are at the bottom
            app.UIAxes2.YLim = [0 app.fs/2];
            app.UIAxes2.XLim = [0 1];
            
            app.UIAxes.XLabel.String = 'Tiempo (s)';
            app.UIAxes.YLabel.String = 'Frecuencia (Hz)';
            
            colorbar(app.UIAxes2)
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainApp, language, signal, t, varargin)
            app.CallingApp = mainApp;
            
            resizeWindow(app);
            
            for i = 1:length(app.UIFigure.Children)
                if ~strcmp(app.UIFigure.Children(i).Type,'uimenu')
                    app.resizeelement(app.UIFigure.Children(i));
                end
            end
            
            app.translate(language);
            
            app.fs = 48000;
            app.mode = 1;
            app.wType = 1;
            app.wSize = app.TamaodeventanaEditField.Value;
            app.wSizeSpectrogram = app.TamaodeventanaEditFieldSpectrogram.Value;
            
            %% Calcular posibles valores de NFFT
            nfft1 = max(256,2^nextpow2(app.wSize));
            i = 1;
            arrayNFFT = {};
            while nfft1 < 66000
                arrayNFFT{i} = num2str(nfft1);
                nfft1 = nfft1*2;
                i = i + 1;
            end
            
            app.nfftDropDown.Items = arrayNFFT;
            app.nfftDropDown.Value = arrayNFFT{1};
            app.nfft = str2double(arrayNFFT{1});
            
            app.updatePlot(signal,t,varargin{:})
            
        end

        % Button pushed function: Button
        function ButtonPushed(app, event)
            app.Button.Enable = 'off';
            
            if(max(abs(app.signal)) > 1)
                signalAux = app.signal./max(app.signal);
            else
                signalAux = app.signal;
            end
            
            sound(signalAux,app.fs);
            
            app.Button.Enable = 'on';
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            app.CallingApp.plotApp_counter = app.CallingApp.plotApp_counter - 1;
            delete(app)
        end

        % Window button down function: UIFigure
        function UIFigureWindowButtonDown(app, event)
            signalPlotXa = app.UIAxes.Position(1);
            signalPlotYa = app.UIAxes.Position(2);
            signalPlotXb = app.UIAxes.Position(3) + signalPlotXa;
            signalPlotYb = app.UIAxes.Position(4) + signalPlotYa;
            currentPoint = event.Source.CurrentPoint;
            
            if (currentPoint(1) > signalPlotXa && currentPoint(1) < signalPlotXb) && (currentPoint(2) > signalPlotYa && currentPoint(2) < signalPlotYb)
                pt = app.UIAxes.CurrentPoint(1,1:2);
                if app.mode == 1 % Modo STFT o espectrograma
                    app.plotCenterLine(pt,1);
                else % Modo FFT
                    app.addDataTip(pt);
                end
                return
            end
            
            spectrumPlotXa = app.UIAxes2.Position(1);
            spectrumPlotYa = app.UIAxes2.Position(2);
            spectrumPlotXb = app.UIAxes2.Position(3) + spectrumPlotXa;
            spectrumPlotYb = app.UIAxes2.Position(4) + spectrumPlotYa;
            
            if (currentPoint(1) > spectrumPlotXa && currentPoint(1) < spectrumPlotXb) && (currentPoint(2) > spectrumPlotYa && currentPoint(2) < spectrumPlotYb) && app.mode ~= 3
                pt = app.UIAxes2.CurrentPoint(1,1:2);
                app.addDataTipSpectrum(pt);
            end
        end

        % Value changed function: TamaodeventanaEditField
        function TamaodeventanaEditFieldValueChanged(app, event)
            num = app.TamaodeventanaEditField.Value;
            
            % Valida el número introducido
            if isempty(num) || isnan(num)
                num = 10001;
                app.TamaodeventanaEditField.Value = num;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif num < 1 || num > 47999
                num = 10001;
                app.TamaodeventanaEditField.Value = num;
                warn = warndlg(app.wsize_warning);
                uiwait(warn);
            elseif ~mod(num,2)
                num = num + 1;
                app.TamaodeventanaEditField.Value = num;
                warn = warndlg(app.even_warning);
                uiwait(warn);
            end
            
            % Dibuja la nueva ventana
            app.wSize = num;
            
            %% Calcular posibles valores de NFFT
            nfft1 = max(256,2^nextpow2(app.wSize));
            i = 1;
            arrayNFFT = {};
            while nfft1 < 66000
                arrayNFFT{i} = num2str(nfft1);
                nfft1 = nfft1*2;
                i = i + 1;
            end
            
            app.nfftDropDown.Items = arrayNFFT;
            app.nfftDropDown.Value = arrayNFFT{1};
            app.nfft = str2double(arrayNFFT{1});

            app.plotCenterLine(app.UIAxes.CurrentPoint(1,1:2),0);
            app.calculateSTFT();

        end

        % Value changed function: nfftDropDown
        function nfftDropDownValueChanged(app, event)
            app.nfft = str2double(app.nfftDropDown.Value);
            
            if app.mode == 2
                app.calculateSTFT();
            else
                app.calculateSpectrogram();
            end
        end

        % Value changed function: VentanaDropDown
        function VentanaDropDownValueChanged(app, event)
            app.wType = find(contains(app.VentanaDropDown.Items,app.VentanaDropDown.Value));
            
            if app.mode == 2
                app.calculateSTFT();
            else
                app.calculateSpectrogram();
            end
        end

        % Value changed function: DropDown
        function DropDownValueChanged(app, event)
            app.mode = find(contains(app.DropDown.Items,app.DropDown.Value));
            app.mode = app.mode(1); %Si hay varios valores, como en el caso de buscar "FT", se queda con el primero
            
            switch app.mode
                case 1
                    app.calculateFT();
                    app.addDataTip(app.UIAxes.CurrentPoint(1,1:2));
                    
                    app.VentanaLabel.Visible = 'off';
                    app.TamaodeventanaLabel.Visible = 'off';
                    app.nfftLabel.Visible = 'off';
                    app.VentanaDropDown.Visible = 'off';
                    app.VentanaDropDown.Enable = 'off';
                    app.TamaodeventanaEditField.Visible = 'off';
                    app.TamaodeventanaEditField.Enable = 'off';
                    app.TamaodeventanaEditFieldSpectrogram.Visible = 'off';
                    app.TamaodeventanaEditFieldSpectrogram.Enable = 'off';
                    app.nfftDropDown.Visible = 'off';
                    app.nfftDropDown.Enable = 'off';
                case 2
                    app.plotCenterLine(app.UIAxes.CurrentPoint(1,1:2),0);
                    app.calculateSTFT();
                    
                    app.VentanaLabel.Visible = 'on';
                    app.TamaodeventanaLabel.Visible = 'on';
                    app.nfftLabel.Visible = 'on';
                    app.VentanaDropDown.Visible = 'on';
                    app.VentanaDropDown.Enable = 'on';
                    app.TamaodeventanaEditField.Visible = 'on';
                    app.TamaodeventanaEditField.Enable = 'on';
                    app.TamaodeventanaEditFieldSpectrogram.Visible = 'off';
                    app.TamaodeventanaEditFieldSpectrogram.Enable = 'off';
                    app.nfftDropDown.Visible = 'on';
                    app.nfftDropDown.Enable = 'on';
                    
                case 3
                    app.calculateSpectrogram();
                    
                    app.VentanaLabel.Visible = 'off';
                    app.TamaodeventanaLabel.Visible = 'on';
                    app.nfftLabel.Visible = 'on';
                    app.VentanaDropDown.Visible = 'off';
                    app.VentanaDropDown.Enable = 'off';
                    app.TamaodeventanaEditField.Visible = 'off';
                    app.TamaodeventanaEditField.Enable = 'off';
                    app.TamaodeventanaEditFieldSpectrogram.Visible = 'on';
                    app.TamaodeventanaEditFieldSpectrogram.Enable = 'on';
                    app.nfftDropDown.Visible = 'on';
                    app.nfftDropDown.Enable = 'on';
            end
        end

        % Value changed function: TamaodeventanaEditFieldSpectrogram
        function TamaodeventanaEditFieldSpectrogramValueChanged(app, event)
            num = app.TamaodeventanaEditFieldSpectrogram.Value;
            
            % Valida el número introducido
            if isempty(num) || isnan(num)
                num = 100;
                app.TamaodeventanaEditFieldSpectrogram.Value = num;
                warn = warndlg(app.numerical_warning);
                uiwait(warn);
            elseif num < 1 || num > 1000
                num = 100;
                app.TamaodeventanaEditFieldSpectrogram.Value = num;
                warn = warndlg(app.wsize_warning);
                uiwait(warn);
            end
            
            app.wSizeSpectrogram = num;
            
            %% Calcular posibles valores de NFFT
            nfft1 = max(256,2^nextpow2(app.wSize));
            i = 1;
            arrayNFFT = {};
            while nfft1 < 66000
                arrayNFFT{i} = num2str(nfft1);
                nfft1 = nfft1*2;
                i = i + 1;
            end
            
            app.nfftDropDown.Items = arrayNFFT;
            app.nfftDropDown.Value = arrayNFFT{1};
            app.nfft = str2double(arrayNFFT{1});

            app.calculateSpectrogram();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.WindowButtonDownFcn = createCallbackFcn(app, @UIFigureWindowButtonDown, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Señal')
            xlabel(app.UIAxes, 'Tiempo (s)')
            ylabel(app.UIAxes, 'Amplitud')
            app.UIAxes.XLim = [0 1];
            app.UIAxes.YLim = [-1 1];
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Transformada de Fourier')
            xlabel(app.UIAxes2, 'Frecuencia (Hz)')
            ylabel(app.UIAxes2, 'Amplitud')
            app.Button = uibutton(app.UIFigure, 'push');
            app.Button.ButtonPushedFcn = createCallbackFcn(app, @ButtonPushed, true);
            app.Button.Icon = 'play_button.png';
            app.Button.Enable = 'off';
            app.Button.Text = {''; ''};
            app.VentanaDropDown = uidropdown(app.UIFigure);
            app.VentanaDropDown.Items = {'Rectangular', 'Hanning'};
            app.VentanaDropDown.ValueChangedFcn = createCallbackFcn(app, @VentanaDropDownValueChanged, true);
            app.VentanaDropDown.Enable = 'off';
            app.VentanaDropDown.Visible = 'off';
            app.VentanaDropDown.Value = 'Rectangular';
            app.nfftDropDown = uidropdown(app.UIFigure);
            app.nfftDropDown.ValueChangedFcn = createCallbackFcn(app, @nfftDropDownValueChanged, true);
            app.nfftDropDown.Enable = 'off';
            app.nfftDropDown.Visible = 'off';
            app.TamaodeventanaEditField = uieditfield(app.UIFigure, 'numeric');
            app.TamaodeventanaEditField.ValueChangedFcn = createCallbackFcn(app, @TamaodeventanaEditFieldValueChanged, true);
            app.TamaodeventanaEditField.Enable = 'off';
            app.TamaodeventanaEditField.Visible = 'off';
            app.TamaodeventanaEditField.Value = 10001;
            app.VentanaLabel = uilabel(app.UIFigure);
            app.VentanaLabel.Visible = 'off';
            app.VentanaLabel.Text = 'Ventana';
            app.TamaodeventanaLabel = uilabel(app.UIFigure);
            app.TamaodeventanaLabel.Visible = 'off';
            app.TamaodeventanaLabel.Text = 'Tamaño de ventana';
            app.nfftLabel = uilabel(app.UIFigure);
            app.nfftLabel.Visible = 'off';
            app.nfftLabel.Text = 'nfft';
            app.DropDown = uidropdown(app.UIFigure);
            app.DropDown.Items = {'FT', 'STFT', 'Espectrograma'};
            app.DropDown.ValueChangedFcn = createCallbackFcn(app, @DropDownValueChanged, true);
            app.DropDown.Value = 'FT';
            app.TamaodeventanaEditFieldSpectrogram = uieditfield(app.UIFigure, 'numeric');
            app.TamaodeventanaEditFieldSpectrogram.Limits = [10 1000];
            app.TamaodeventanaEditFieldSpectrogram.ValueDisplayFormat = '%.0f';
            app.TamaodeventanaEditFieldSpectrogram.ValueChangedFcn = createCallbackFcn(app, @TamaodeventanaEditFieldSpectrogramValueChanged, true);
            app.TamaodeventanaEditFieldSpectrogram.Enable = 'off';
            app.TamaodeventanaEditFieldSpectrogram.Visible = 'off';
            app.TamaodeventanaEditFieldSpectrogram.Value = 100;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = visualization_App(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end